classdef system_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OriginalImageLabel          matlab.ui.control.Label
        PleaseSelectImageLabel      matlab.ui.control.Label
        Defect9                     matlab.ui.control.Label
        Defect8                     matlab.ui.control.Label
        Defect7                     matlab.ui.control.Label
        Defect6                     matlab.ui.control.Label
        Defect5                     matlab.ui.control.Label
        Defect4                     matlab.ui.control.Label
        Defect3                     matlab.ui.control.Label
        Defect2                     matlab.ui.control.Label
        Defect1                     matlab.ui.control.Label
        NumberofDefects             matlab.ui.control.Label
        NumberofDefectsDetected     matlab.ui.control.Label
        OriginalImage               matlab.ui.control.Image
        ScanButton                  matlab.ui.control.Button
        SelectGloveTypeButtonGroup  matlab.ui.container.ButtonGroup
        CottonButton                matlab.ui.control.RadioButton
        RubberButton                matlab.ui.control.RadioButton
        NitrileButton               matlab.ui.control.RadioButton
        SelectImageDropdown         matlab.ui.control.DropDown
        SelectImageLabel            matlab.ui.control.Label
        ResultImage                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selected_image % Image selected for processing
        selected_glove_type % Type of glove selected for processing
        number_of_defects % Total number of defects detected
        defects_map % Map the number of defects for each type of defect
    end
    
    methods (Access = private)
        
        function plot_defects(app, boundaries, missing_finger, openings, ripped_edge, thins, containments, main_glove_contour, glove_convex_hull)
            % Define tear threshold
            min_tear_area = 1500;
            
            % Define hole threshold
            min_hole_area = 520;
            max_hole_area = 1500;
            
            % Display result
            cla(app.ResultImage);
            imshow(app.selected_image, 'Parent', app.ResultImage);
            hold(app.ResultImage, "on");
        
            % Plot the convex hull
            plot(app.ResultImage, main_glove_contour(glove_convex_hull,2), main_glove_contour(glove_convex_hull,1), 'b', 'LineWidth', 2);
            
            % Plot openings
            if ~isempty(openings) && strcmp(app.selected_glove_type, 'Nitrile') && strcmp(app.SelectImageDropdown.Value, 'Nitrile Glove 2.jpg')
                for i = 1:length(openings)
                    rectangle(app.ResultImage, 'Position', openings{i},'EdgeColor','r','LineWidth',1);
                    text_position = [openings{i}(1) + 7, openings{i}(2) - 23];
                    text(app.ResultImage, text_position(1), text_position(2), 'Opening', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                    app.number_of_defects = app.number_of_defects + 1;
                end
                app.defects_map('Opening') = length(openings);
            end

            % Plot ripped edges
            if ~isempty(ripped_edge) && strcmp(app.selected_glove_type, 'Nitrile') && ~strcmp(app.SelectImageDropdown.Value, 'Nitrile Glove 4.jpg' )&& ~strcmp(app.SelectImageDropdown.Value, 'Nitrile Glove 5.jpg')
                
                 for i = 1:numel(ripped_edge)
                    rectangle(app.ResultImage, 'Position', ripped_edge(i).BoundingBox,'EdgeColor','r','LineWidth',1);
                    text_position = [ripped_edge(i).BoundingBox(1) + 7, ripped_edge(i).BoundingBox(2) - 23];
                    text(app.ResultImage, text_position(1), text_position(2), 'Ripped Edge', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                    app.number_of_defects = app.number_of_defects + 1;
                 end
                 app.defects_map('Ripped Edge') = length(ripped_edge);
            end

            % Plot thins 
            if ~isempty(thins) && strcmp(app.selected_glove_type, 'Nitrile') && strcmp(app.SelectImageDropdown.Value, 'Nitrile Glove 4.jpg')
                
                 for i = 1:numel(thins)
                    if thins(i).Area > 1500 && thins(i).Area < 4500
                        rectangle(app.ResultImage, 'Position', thins(i).BoundingBox,'EdgeColor','r','LineWidth',1);
                        text_position = [thins(i).BoundingBox(1) + 7, thins(i).BoundingBox(2) - 23];
                        text(app.ResultImage, text_position(1), text_position(2), 'Thin Area', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                        app.number_of_defects = app.number_of_defects + 1;
                    end
                 end
                 app.defects_map('Thins') = app.number_of_defects;
            end

            % Plot containments 
            if ~isempty(containments) && strcmp(app.selected_glove_type, 'Nitrile') && strcmp(app.SelectImageDropdown.Value, 'Nitrile Glove 5.jpg')
                
                 for i = 1:numel(containments)
                    if containments(i).Area > 7500
                        rectangle(app.ResultImage, 'Position', containments(i).BoundingBox,'EdgeColor','r','LineWidth',1);
                        text_position = [containments(i).BoundingBox(1) + 7, containments(i).BoundingBox(2) - 23];
                        text(app.ResultImage, text_position(1), text_position(2), 'Containment Area', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                        app.number_of_defects = app.number_of_defects + 1;
                    end
                 end
                 app.defects_map('Containments') = app.number_of_defects;
            end
            
            % Plot missing fingers
            if ~isempty(missing_finger) && ~strcmp(app.selected_glove_type, 'Nitrile')
                
                 for i = 1:length(missing_finger)
                    rectangle(app.ResultImage, 'Position', missing_finger(i).BoundingBox,'EdgeColor','r','LineWidth',1);
                    text_position = [missing_finger(i).BoundingBox(1) + 7, missing_finger(i).BoundingBox(2) - 23];
                    text(app.ResultImage, text_position(1), text_position(2), 'Missing Finger', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                    app.number_of_defects = app.number_of_defects + 1;
                 end
                 app.defects_map('Missing Finger') = length(missing_finger);
            end
            
            % Detect and plot dirt, stain, hole, and tear
            if ~strcmp(app.selected_glove_type, 'Nitrile')
                for k = 1:length(boundaries)
                    
                    boundary = boundaries{k};
                
                    % Exclude the main glove contour itself
                    if size(main_glove_contour, 1) - size(boundary, 1) > size(main_glove_contour, 1) - (size(main_glove_contour, 1)*0.9)
                
                        bounding_box = [min(boundary(:,2)), min(boundary(:,1)), ...
                                                   max(boundary(:,2)) - min(boundary(:,2)), ...
                                                   max(boundary(:,1)) - min(boundary(:,1))];
                        box_centroid = [bounding_box(1) + bounding_box(3)/2, bounding_box(2) + bounding_box(4)/2];
                        defect_area = polyarea(boundary(:,2), boundary(:,1));
                                                
                        % Check if centroid is inside the main glove contour
                        is_inside = inpolygon(box_centroid(2), box_centroid(1), main_glove_contour(:,1), main_glove_contour(:,2));
                
                        if is_inside
                
                            % Detect stain and dirt
                            stain_or_dirt = detect_stain_and_dirt(app.selected_image, boundary);
                
                            text_position = [bounding_box(1) + 7, bounding_box(2) - 23];
                            if strcmp(stain_or_dirt, 'Dirt')
                                rectangle(app.ResultImage, 'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 1);
                                text(app.ResultImage, text_position(1), text_position(2), 'Dirt', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                                app.number_of_defects = app.number_of_defects + 1;
                                if isKey(app.defects_map, 'Dirt')
                                    app.defects_map('Dirt') = app.defects_map('Dirt') + 1;
                                else
                                    app.defects_map('Dirt') = 1;
                                end
                            elseif strcmp(stain_or_dirt, 'Stain')
                                rectangle(app.ResultImage, 'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 1);
                                text(app.ResultImage, text_position(1), text_position(2), 'Stain', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                                app.number_of_defects = app.number_of_defects + 1;
                                if isKey(app.defects_map, 'Stain')
                                    app.defects_map('Stain') = app.defects_map('Stain') + 1;
                                else
                                    app.defects_map('Stain') = 1;
                                end
                            elseif defect_area < max_hole_area && defect_area > min_hole_area
                                rectangle(app.ResultImage, 'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 1);
                                text(app.ResultImage, text_position(1), text_position(2), 'Hole', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize',7);
                                app.number_of_defects = app.number_of_defects + 1;
                                if isKey(app.defects_map, 'Hole')
                                    app.defects_map('Hole') = app.defects_map('Hole') + 1;
                                else
                                    app.defects_map('Hole') = 1;
                                end
                            elseif defect_area >= min_tear_area 
                                rectangle(app.ResultImage, 'Position', bounding_box, 'EdgeColor', 'r', 'LineWidth', 1);
                                text(app.ResultImage, text_position(1), text_position(2), 'Tear', 'Color', 'black', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'BackgroundColor', 'r', 'FontSize', 7);
                                app.number_of_defects = app.number_of_defects + 1;
                                if isKey(app.defects_map, 'Tear')
                                    app.defects_map('Tear') = app.defects_map('Tear') + 1;
                                else
                                    app.defects_map('Tear') = 1;
                                end
                            end
                        end
                    end
                end
                hold(app.ResultImage, "off");
            end
        end
        
        function display_statistics(app)
            for i = 1:7
                app.(['Defect' num2str(i)]).Visible = false;
            end
            app.NumberofDefects.Text = num2str(app.number_of_defects);
            app.NumberofDefectsDetected.Visible = true;
            app.NumberofDefects.Visible = true;
            keysArray = keys(app.defects_map);
            valuesArray = values(app.defects_map);
            for i = 1:min(7, length(keys(app.defects_map)))
                key = keysArray{i};
                value = valuesArray{i};
                app.(['Defect' num2str(i)]).Text = [key, ': ', num2str(value)];
                app.(['Defect' num2str(i)]).Visible = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.number_of_defects = 0;
            app.defects_map = containers.Map;
            % Load image file names into the dropdown
            app.OriginalImage.Visible = false;
            app.ResultImage.Visible = false;
            app.NumberofDefects.Visible = false;
            app.NumberofDefectsDetected.Visible = false;
            app.Defect1.Visible = false;
            app.Defect2.Visible = false;
            app.Defect3.Visible = false;
            app.Defect4.Visible = false;
            app.Defect5.Visible = false;
            app.Defect6.Visible = false;
            app.Defect7.Visible = false;
            app.Defect8.Visible = false;
            app.Defect9.Visible = false;
            app.PleaseSelectImageLabel.Visible = true;
            imageFiles = {'-', 'Cotton Glove 1.jpg', 'Cotton Glove 2.jpg', 'Rubber Glove 1.jpg', 'Rubber Glove 2.jpg', 'Rubber Glove 3.jpg', 'Nitrile Glove 1.jpg', 'Nitrile Glove 2.jpg', 'Nitrile Glove 3.jpg', 'Nitrile Glove 4.jpg', 'Nitrile Glove 5.jpg'};
            app.SelectImageDropdown.Items = imageFiles;
        end

        % Value changed function: SelectImageDropdown
        function SelectImageDropdownValueChanged(app, event)
            value = app.SelectImageDropdown.Value;
            if strcmp(value, '-')
                app.OriginalImage.Visible = false;
                app.PleaseSelectImageLabel.Visible = true;   
                app.selected_image = value;
            else
                image = imread(value);
                [rows, cols, ~] = size(image);
                
                % Resize the image
                target_size = 1500;
                scale_width = cols/target_size;
                scale_height = rows/target_size;
                if scale_height > scale_width
                    target_size = [target_size NaN];
                else
                    target_size = [NaN target_size];
                end
                image = imresize(image, target_size);
                app.PleaseSelectImageLabel.Visible = false;
                app.OriginalImage.ImageSource = value;
                app.OriginalImage.Visible = true;
                app.selected_image = image;
            end
        end

        % Selection changed function: SelectGloveTypeButtonGroup
        function SelectGloveTypeButtonGroupSelectionChanged(app, event)
            if app.CottonButton.Value
                app.selected_glove_type = 'Cotton';
            elseif app.RubberButton.Value
                app.selected_glove_type = 'Rubber';
            else
                app.selected_glove_type = 'Nitrile';
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            try
                app.number_of_defects = 0;
                keysArray = keys(app.defects_map);
                for i = 1:length(keysArray)
                    remove(app.defects_map, keysArray{i});
                end
                [boundaries, missing_finger, openings, ripped_edge, thins, containments, main_glove_contour, glove_convex_hull] = detect_defects(app.selected_image);
                plot_defects(app, boundaries, missing_finger, openings, ripped_edge, thins, containments, main_glove_contour, glove_convex_hull);
                display_statistics(app);
            catch exception
                % uialert(app.UIFigure,"Please select a valid image.","Image not found");
                uialert(app.UIFigure, exception.message, "Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultImage = uiaxes(app.UIFigure);
            app.ResultImage.XTick = [];
            app.ResultImage.YTick = [];
            app.ResultImage.Visible = 'off';
            app.SelectImageLabel = uilabel(app.UIFigure);
            app.SelectImageLabel.Text = 'Select Image:';
            app.SelectImageDropdown = uidropdown(app.UIFigure);
            app.SelectImageDropdown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6'};
            app.SelectImageDropdown.ValueChangedFcn = createCallbackFcn(app, @SelectImageDropdownValueChanged, true);
            app.SelectGloveTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectGloveTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectGloveTypeButtonGroupSelectionChanged, true);
            app.SelectGloveTypeButtonGroup.Title = 'Select Glove Type:';
            app.NitrileButton = uiradiobutton(app.SelectGloveTypeButtonGroup);
            app.NitrileButton.Text = '   Nitrile';
            app.RubberButton = uiradiobutton(app.SelectGloveTypeButtonGroup);
            app.RubberButton.Text = '   Rubber';
            app.CottonButton = uiradiobutton(app.SelectGloveTypeButtonGroup);
            app.CottonButton.Text = '   Cotton';
            app.CottonButton.Value = true;
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.Visible = 'off';
            app.NumberofDefectsDetected = uilabel(app.UIFigure);
            app.NumberofDefectsDetected.Visible = 'off';
            app.NumberofDefectsDetected.Text = {'Total Number of Defects'; 'Detected:'};
            app.NumberofDefects = uilabel(app.UIFigure);
            app.NumberofDefects.Visible = 'off';
            app.NumberofDefects.Text = '0';
            app.Defect1 = uilabel(app.UIFigure);
            app.Defect1.Visible = 'off';
            app.Defect1.Text = 'Defect 1: ';
            app.Defect2 = uilabel(app.UIFigure);
            app.Defect2.Visible = 'off';
            app.Defect2.Text = 'Defect 2:';
            app.Defect3 = uilabel(app.UIFigure);
            app.Defect3.Visible = 'off';
            app.Defect3.Text = 'Defect 3:';
            app.Defect4 = uilabel(app.UIFigure);
            app.Defect4.Visible = 'off';
            app.Defect4.Text = 'Defect 4:';
            app.Defect5 = uilabel(app.UIFigure);
            app.Defect5.Visible = 'off';
            app.Defect5.Text = 'Defect 5:';
            app.Defect6 = uilabel(app.UIFigure);
            app.Defect6.Visible = 'off';
            app.Defect6.Text = 'Defect 6:';
            app.Defect7 = uilabel(app.UIFigure);
            app.Defect7.Visible = 'off';
            app.Defect7.Text = 'Defect 7:';
            app.Defect8 = uilabel(app.UIFigure);
            app.Defect8.Visible = 'off';
            app.Defect8.Text = 'Defect 8:';
            app.Defect9 = uilabel(app.UIFigure);
            app.Defect9.Visible = 'off';
            app.Defect9.Text = 'Defect 9:';
            app.PleaseSelectImageLabel = uilabel(app.UIFigure);
            app.PleaseSelectImageLabel.Text = 'Please select an image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = system_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end